classdef DobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        TeachActiveLamp                 matlab.ui.control.Lamp
        TeachActiveLampLabel            matlab.ui.control.Label
        ConfirmCartesianMovementButton  matlab.ui.control.Button
        StartTeachButton                matlab.ui.control.Button
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        ChangeIndividualJointAngleLabel  matlab.ui.control.Label
        Joint6Slider                    matlab.ui.control.Slider
        Joint6SliderLabel               matlab.ui.control.Label
        Joint5Slider                    matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        Joint4Slider                    matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        Joint3Slider                    matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        EditField6                      matlab.ui.control.NumericEditField
        EditField5                      matlab.ui.control.NumericEditField
        EditField4                      matlab.ui.control.NumericEditField
        EditField3                      matlab.ui.control.NumericEditField
        EditField2                      matlab.ui.control.NumericEditField
        EditField1                      matlab.ui.control.NumericEditField
        MovetoCartesianCoordinateLabel  matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        FinishSimulationButton          matlab.ui.control.Button
        RobotDropDown                   matlab.ui.control.DropDown
        RobotDropDownLabel              matlab.ui.control.Label
        EMERGENCYSTOPButton             matlab.ui.control.Button
        StartSimulationButton           matlab.ui.control.Button
    end


    properties (Access = public)
        arduino;                    % Arduino object variable
        auboI5;                     % Aubo i5 object variable
        dobotMagician;              % Dobot Magican object variable
        businessCards;              % Business Card object variable
        la2;                        % Lab assessment 2 general object variable
        logFile;                    % Log file variable
        fig;                        % Figure plotting variable
        currentRobot;               % Variable for which robot is currently selected
        simulationMode;             % GUI Mode variable
        eStopInit = false;          % eStop initialise check variable
        strtBtnPressed = false;     % Start button press variable
    end
    
    methods (Access = public)
        
        function guiEStop(app)
            app.strtBtnPressed = false;
            while(~app.strtBtnPressed)
                pause(0.5);

                app.irlEStop(app.arduino.CheckButtonPressed());
            end
            
        end
        % Function to determine e-stop activity based off arduino reading
        function RealEstopReading(app, eStopValue)
            % Checking if the arduino value is the same as the current
            % eStopPressed gui property value
            % Indicates that the arduino estop has been pressed
            if eStopValue ~= app.eStopPressed
                % Call estop callback to update value
                app.EmergencyStopButtonPushed();
            end
        end  

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Creating the figure showing to show the main demo
            app.fig = figure(1); % Creating figure to simulate robots
            hold on; axis([-1 1.5 -1.25 1.25 -0.76 1.5]); camlight;

            % Create a debugging log file 
            app.logFile = log4matlab('logFile.log');
            app.logFile.SetCommandWindowLevel(app.logFile.DEBUG); % Setting the log level to debug

            % Creating the Environment
            LabAssessment2.CreateEnvironment(app.logFile);
            
            % Creating objects for the necessary classes
            app.arduino = Arduino();
            app.auboI5 = AuboI5(eye(4), app.logFile);
            app.dobotMagician = DMagician(eye(4) * transl(0,0.5,0), app.logFile);
            app.hand = Hand(eye(4) * transl(2,2,2), app.logFile);
            app.businessCards = PlayingCards(eye(4) * transl(0.15, 0.7, 0.01), app.logFile);
            app.la2 = LabAssessment2();

            % Setting the current robot
            app.currentRobot = app.auboI5;
            app.RobotDropDownValueChanged(); % Updating slider values
        end

        % Button pushed function: ConfirmCartesianMovementButton
        function ConfirmCartesianMovementButtonPushed(app, event)

            % Getting each value of x, y, z for the cartesian point movement
            newPos = [app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value];
            newOrient = deg2rad([str2double(app.REditField.Value), str2double(app.PEditField.Value), str2double(app.PEditField.Value)]);
            % Switch case for selecting robot using dropdown
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the cartesian goal of the Aubo i5
                    app.auboI5.MoveToCartesian(newPos, newOrient);
                    app.logFile.mlog = {app.logFile.DEBUG,'ConfirmCartesianMovementButtonPushed', ...
                    'Cartesian movement request sent to Aubo i5'};

                case 'Dobot Magician'
                    % Updating the cartesian goal of the Dobot Magician
                    app.dobotMagician.MoveToCartesian(newPos);
                    app.logFile.mlog = {app.logFile.DEBUG,'ConfirmCartesianMovementButtonPushed', ...
                    'Cartesian movement request sent to Dobot Magician'};
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            % Updating e-stop property to opposite of its prior state
            app.eStopInit = ~app.eStopInit;

            % When eStop is pressed, ensure start button is showing false
            if app.eStopInit
                % Setting continue button to false (for two step starting)
                app.continuePressed = false;
                app.RunningLamp.Color = [1 0 0]; % Updating colour of GUI lamp
                app.logFile.mlog = {app.logFile.DEBUG,'EMERGENCYSTOPButtonPushed', ...
                    'EMERGENCY STOP pressed, robot function paused'};
                app.arduino.EstopOn(); % Forcing arduino estop on
                app.eStopPause();
            else
                app.arduino.EstopOff(); % Forcing arduino estop off
                % Logging that continue button can be pressed now (e-stop off)
                app.RunningLamp.Color = [0 0.8 0.2]; % Updating colour of GUI lamp
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'EMERGENCY STOP disengaged, press continue when ready'};
            end
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            % Checking that the eStop is off 
            if ~app.eStopInit
                % Toggling continuePressed property to opposite state
                app.strtBtnPressed = true;
                app.RunningLamp.Color = [0 0.8 0.2]; % Updating colour of GUI lamp
                app.logFile.mlog = {app.logFile.DEBUG,'StartButtonPushed', ...
                    'Robot function started'};
            else
                % Add to log file that the robot was given a start command while eStop active
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Cannot start/continue robot operation if e-stop has been pressed'};
            end
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            % Setting up 6th joint depending on if Aubo i5 or Dobot Magician is selected
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Setting current robot property to the Aubo i5
                    app.currentRobot = app.auboI5;

                    % Enabling joint 6 movement
                    app.Joint6Slider.Enable = true;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-90,  90];
                    app.Joint2Slider.Limits = [5,    175];
                    app.Joint3Slider.Limits = [-175, 175];
                    app.Joint4Slider.Limits = [-350, 360];
                    app.Joint5Slider.Limits = [-175, 175];
                    app.Joint6Slider.Limits = [-360, 360];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.auboI5.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);
                    app.Joint6Slider.Value = currentJointAngles(6);

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Aubo i5 control'};

                case 'Dobot Magician'
                    % Setting current robot property to the dobot magician
                    app.currentRobot = app.dobotMagician;

                    % Disabling joint 6 movement
                    app.Joint6Slider.Enable = false;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-135, 135];
                    app.Joint2Slider.Limits = [5,    80];
                    app.Joint3Slider.Limits = [-5,   85];
                    app.Joint4Slider.Limits = [-180, 180];
                    app.Joint5Slider.Limits = [-85,  85];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.dobotMagician.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Dobot Magician control'};
            end
        end

        % Button pushed function: FinishSimulationButton
        function FinishSimulationButtonPushed(app, event)
            % Delete app and close all objects
            close(app.fig); delete(app);
            app.logFile.mlog = {app.logFile.DEBUG,'EndDemonstrationButtonPushed', ...
                    'Finish Simulation requested'};
        end

        % Button pushed function: StartTeachButton
        function StartTeachButtonPushed(app, event)
            % Changing the GUI display relative to the mode
                    app.TeachActiveLamp.Color = [0 0.8 0.2];
                    app.RobotDropDown.Enable = "on";
                    app.ConfirmCartesianMovementButton.Enable = "on";
                    app.Joint1Slider.Enable = "on";
                    app.Joint2Slider.Enable = "on";
                    app.Joint3Slider.Enable = "on";
                    app.Joint4Slider.Enable = "on";
                    app.Joint5Slider.Enable = "on";

                    % Switch case to determine if slider 6 should be on or off
                    switch app.RobotDropDown.Value
                        case 'Aubo I5'
                            % Ensure slider 6 on for Aubo i5
                            app.Joint6Slider.Visible = "on";

                        case 'Dobot Magician'
                            % Ensure slider 6 off for Dobot
                            app.Joint6Slider.Visible = "off";
                    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = {'Start'; 'Simulation'};
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot:';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'Dobot Magician', 'Desk Arm 5'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'Dobot Magician';
            app.FinishSimulationButton = uibutton(app.UIFigure, 'push');
            app.FinishSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSimulationButtonPushed, true);
            app.FinishSimulationButton.Text = {'Finish'; 'Simulation'};
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.MovetoCartesianCoordinateLabel = uilabel(app.UIFigure);
            app.MovetoCartesianCoordinateLabel.Text = 'Move to Cartesian Co-ordinate';
            app.EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField6 = uieditfield(app.UIFigure, 'numeric');
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-175 175];
            app.Joint1Slider.MajorTicks = [];
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Tag = '1';
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-175 175];
            app.Joint2Slider.MajorTicks = [];
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Tag = '2';
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-175 175];
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Tag = '3';
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-175 175];
            app.Joint4Slider.MajorTicks = [];
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Tag = '4';
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-175 175];
            app.Joint5Slider.MajorTicks = [];
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Tag = '5';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.MajorTicks = [];
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Tag = '6';
            app.ChangeIndividualJointAngleLabel = uilabel(app.UIFigure);
            app.ChangeIndividualJointAngleLabel.Text = 'Change Individual Joint Angle';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.StartTeachButton = uibutton(app.UIFigure, 'push');
            app.StartTeachButton.ButtonPushedFcn = createCallbackFcn(app, @StartTeachButtonPushed, true);
            app.StartTeachButton.Text = {'Start'; 'Teach'; ''};
            app.ConfirmCartesianMovementButton = uibutton(app.UIFigure, 'push');
            app.ConfirmCartesianMovementButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmCartesianMovementButtonPushed, true);
            app.ConfirmCartesianMovementButton.Text = {'Confirm'; 'Cartesian'; 'Movement'};
            app.TeachActiveLampLabel = uilabel(app.UIFigure);
            app.TeachActiveLampLabel.Text = 'Teach Active';
            app.TeachActiveLamp = uilamp(app.UIFigure);
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R:';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P:';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y:';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end